classdef INTEDapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        DiscretizationPanel      matlab.ui.container.Panel
        VoronoiButton            matlab.ui.control.Button
        CellDecompositionButton  matlab.ui.control.Button
        PRMButton                matlab.ui.control.Button
        GraphSearchingPanel      matlab.ui.container.Panel
        BFSButton                matlab.ui.control.Button
        AButton                  matlab.ui.control.Button
        TargetXEditFieldLabel    matlab.ui.control.Label
        TargetXEditField         matlab.ui.control.NumericEditField
        TargetYEditFieldLabel    matlab.ui.control.Label
        TargetYEditField         matlab.ui.control.NumericEditField
        ROSinterfacePanel        matlab.ui.container.Panel
        ConnectButton            matlab.ui.control.Button
        FollowPathButton         matlab.ui.control.Button
        DisconnectButton         matlab.ui.control.Button
        RESETButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        Xinit
        Yinit
        Xfinal
        Yfinal
        rows
        cols
        IMGPROCES
        IMGOBJECTS
        IMGORIGINAL
        nodeList
        currentNode
        desiredNode
        neighbors
        path
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Cargar mapa
            app.Xinit=238;
            app.Yinit=269;
            %app.Xfinal=340;
            %app.Yfinal=212;
            load('3CAJAS.mat');
            app.IMGPROCES=occupancyMatrix(map); %Cambio de BinaryOccupancyGrid a matriz
            app.IMGPROCES=mat2gray(app.IMGPROCES); %Cambio de matriz a imagen BW
            app.IMGORIGINAL=app.IMGPROCES;

            hold(app.UIAxes)
            imshow(app.IMGORIGINAL,'Parent',app.UIAxes)
            app.UIAxes.XLim=[170 420];
            app.UIAxes.YLim=[140 340];
            
            % Preprocesamiento
            app.IMGPROCES=imbinarize(app.IMGPROCES); %Cambio a imagen de 1 canal
            app.IMGPROCES=bwmorph(app.IMGPROCES,'clean'); % Quita píxeles sueltos
            app.IMGPROCES=bwmorph(app.IMGPROCES,'spur'); %Quita spur píxels
            app.IMGPROCES=bwmorph(app.IMGPROCES,'close'); %Closing para eliminación de ruido impulsorial en píxels blancos
            app.IMGPROCES=imdilate(app.IMGPROCES,strel('disk', double(round(0.177/resolution)+1)));
            app.IMGPROCES=bwmorph(app.IMGPROCES,'open'); %Opening para eliminación de ruido impulsorial en píxels negros
            [app.rows, app.cols, ~] = size(app.IMGPROCES); %Guarda dimensiones de la imagen original
            
            % MÁSCARA
            MAX_PERIM=(2*length(app.IMGPROCES(:,1)))+(2+length(app.IMGPROCES(1,:)))-4;
            PERIM=bwperim(app.IMGPROCES);%Imagen con los perímetros de todos los elementos
            PointMask=regionprops(PERIM,'PixelList');%Lista de píxeles en cada perímetro
            index=1;
            max_per=1;
            for per=1:length(PointMask) %Iteración por cada perímetro encontrado
                if (length(PointMask(per).PixelList(:,1))>max_per) && (length(PointMask(per).PixelList(:,1))<MAX_PERIM) %Búsqueda del perímetro más grande sin contar el externo de la imagen
                    index=per;
                    max_per=length(PointMask(per).PixelList(:,1));
                end
            end
            BoundMask=convhull(PointMask(index).PixelList(:,1),PointMask(index).PixelList(:,2)); %Busca puntos del perímetro que forman un cerco alrededor
            MASK= poly2mask(PointMask(index).PixelList(BoundMask,1),PointMask(index).PixelList(BoundMask,2),app.rows,app.cols); %Crea máscara con la forma del cerco
            MASK=imerode(MASK,strel('disk', double(round(0.177/resolution)+2)));
            app.IMGOBJECTS=app.IMGPROCES.*MASK;

        end

        % Button pushed function: VoronoiButton
        function VoronoiButtonPushed(app, event)
            IMGVOR=bwmorph(~app.IMGPROCES,'skel',Inf);
            VoronoiPoint=regionprops(IMGVOR,'PixelList');%Lista de píxeles en cada Voronoi
            for i=2:size(VoronoiPoint,1)
                for j=1:size(VoronoiPoint(i).PixelList,1)
                    IMGVOR(VoronoiPoint(i).PixelList(j,2),VoronoiPoint(i).PixelList(j,1))=0;
                end
            end
            VoronoiPoint=VoronoiPoint(1).PixelList;
            
            % Juntar punto inicial al grafo
            if IMGVOR(app.Yinit,app.Xinit)==0
                distInit=sum((VoronoiPoint-[app.Xinit app.Yinit]).^ 2, 2);
                closestInit=VoronoiPoint(distInit==min(distInit),:);
                if size(closestInit,1)>1
                    closestInit=closestInit(1,:);
                end
                xInitPath=round(linspace(app.Xinit, closestInit(1),max(abs(app.Xinit-closestInit(1)),abs(app.Yinit-closestInit(2)))+1));
                yInitPath=round(linspace(app.Yinit, closestInit(2),max(abs(app.Xinit-closestInit(1)),abs(app.Yinit-closestInit(2)))+1));
                for i=1:size(xInitPath,2)
                    IMGVOR(yInitPath(i),xInitPath(i))=1;
                end
            end
            
            % Juntar punto final al grafo
            if IMGVOR(app.Yfinal,app.Xfinal)==0
                distFinal=sum((VoronoiPoint-[app.Xfinal app.Yfinal]).^ 2, 2);
                closestFinal=VoronoiPoint(distFinal==min(distFinal),:);
                if size(closestFinal,1)>1
                    closestFinal=closestFinal(1,:);
                end
                xFinalPath=round(linspace(app.Xfinal, closestFinal(1),max(abs(app.Xfinal-closestFinal(1)),abs(app.Yfinal-closestFinal(2)))+1));
                yFinalPath=round(linspace(app.Yfinal, closestFinal(2),max(abs(app.Xfinal-closestFinal(1)),abs(app.Yfinal-closestFinal(2)))+1));
                for i=1:size(xFinalPath,2)
                    IMGVOR(yFinalPath(i),xFinalPath(i))=1;
                end
            end
            
            VoronoiPoint2=regionprops(IMGVOR,'PixelList');%Lista de píxeles en cada Voronoi
            VoronoiPoint2=VoronoiPoint2(1).PixelList;
            scatter(VoronoiPoint2(:,1),VoronoiPoint2(:,2),5,'m','filled','Parent',app.UIAxes);
            %hold off
            
            app.neighbors={};
            for n=1:size(VoronoiPoint2,1)
                neighbor=[];
                for m=1:size(VoronoiPoint2,1)
                    if (n~=m)&&(pdist([VoronoiPoint2(n,:);VoronoiPoint2(m,:)])<2)
                        neighbor=[neighbor m];
                    end
                end
                app.neighbors{end+1} = neighbor;
            end
            
            app.nodeList=VoronoiPoint2;
            app.currentNode=intersect(find(VoronoiPoint2(:,1)==app.Xinit),find(VoronoiPoint2(:,2)==app.Yinit));
            app.desiredNode=intersect(find(VoronoiPoint2(:,1)==app.Xfinal),find(VoronoiPoint2(:,2)==app.Yfinal));
        end

        % Button pushed function: CellDecompositionButton
        function CellDecompositionButtonPushed(app, event)
            FRONTIERS=zeros(app.rows,app.cols);
            [OBJETOS, NUMERO] = bwlabel(app.IMGOBJECTS); %Numera los píxeles de los objetos de la imagen, asignando un mismo número a píxeles de un mismo objeto.
            LISTA=regionprops(OBJETOS,'PixelList'); %Guarda las coordenadas de los píxeles para cada objeto de la imagen
            for n=1:NUMERO
                if(length(LISTA(n).PixelList(:,1))>50)
                    xMin=min(LISTA(n).PixelList(:,1));
                    xMinPosit=find(LISTA(n).PixelList(:,1)==xMin,1,'first');
                    xMinCoord=LISTA(n).PixelList(xMinPosit,:);
                    %Hacia arriba
                    cont=true;
                    cont2=0;
                    while cont
                        cont2=cont2+1;
                        if (app.IMGPROCES(xMinCoord(2)-cont2,xMinCoord(1))==1)&&not((all(ismember([xMinCoord(1) xMinCoord(2)-cont2],LISTA(n).PixelList(:,:)))))
                            cont=false;
                        end
                    end
                    %line([xMinCoord(1) xMinCoord(1)],[xMinCoord(2) xMinCoord(2)-cont2],'Color','m')
                    upper=xMinCoord(2)-cont2;
                    %Hacia abajo
                    cont=true;
                    cont2=0;
                    while cont
                        cont2=cont2+1;
                        if (app.IMGPROCES(xMinCoord(2)+cont2,xMinCoord(1))==1)&&not((all(ismember([xMinCoord(1) xMinCoord(2)+cont2],LISTA(n).PixelList(:,:)))))
                            cont=false;
                        end
                    end
                    %line([xMinCoord(1) xMinCoord(1)],[xMinCoord(2) xMinCoord(2)+cont2],'Color','m')
                    lower=xMinCoord(2)+cont2;
                    FRONTIERS(upper:lower,xMinCoord(1))=1;
                    
                    xMax=max(LISTA(n).PixelList(:,1));
                    xMaxPosit=find(LISTA(n).PixelList(:,1)==xMax,1,'first');
                    xMaxCoord=LISTA(n).PixelList(xMaxPosit,:);
                    %Hacia arriba
                    cont=true;
                    cont2=0;
                    while cont
                        cont2=cont2+1;
                        if (app.IMGPROCES(xMaxCoord(2)-cont2,xMaxCoord(1))==1)&&not((all(ismember([xMaxCoord(1) xMaxCoord(2)-cont2],LISTA(n).PixelList(:,:)))))
                            cont=false;
                        end
                    end
                    %line([xMaxCoord(1) xMaxCoord(1)],[xMaxCoord(2) xMaxCoord(2)-cont2],'Color','m')
                    upper=xMaxCoord(2)-cont2;
                    %Hacia abajo
                    cont=true;
                    cont2=0;
                    while cont
                        cont2=cont2+1;
                        if (app.IMGPROCES(xMaxCoord(2)+cont2,xMaxCoord(1))==1)&&not((all(ismember([xMaxCoord(1) xMaxCoord(2)+cont2],LISTA(n).PixelList(:,:)))))
                            cont=false;
                        end
                    end
                    %line([xMaxCoord(1) xMaxCoord(1)],[xMaxCoord(2) xMaxCoord(2)+cont2],'Color','m')
                    lower=xMaxCoord(2)+cont2;
                    FRONTIERS(upper:lower,xMaxCoord(1))=1;
                end
            end
            
            IMGCELLS=imdilate(app.IMGPROCES,strel('line',4,0));
            FRONTIERS2=FRONTIERS.*not(IMGCELLS);%añadir un imdilate horiz. a imgcells
            [OBJETOS2, ~] = bwlabel(FRONTIERS2);
            centroides=regionprops(OBJETOS2,'Centroid');
            centroids=round(cat(1,centroides.Centroid));
            scatter(centroids(:,1),centroids(:,2),20,'g','filled','Parent',app.UIAxes)
            
            % Calculamos las celdas que tocan en cada frontera
            numNodes=size(centroids(:,1));
            ADJ=~or(FRONTIERS,app.IMGPROCES);
            CELLS=bwconncomp(ADJ);
            labeled=labelmatrix(CELLS);
            neighborCells=[];
            for n=1:numNodes(1)
                leftVal=0;
                rightVal=0;
                count=0;
                while leftVal==0
                    count=count+1;
                    leftVal=labeled(centroids(n,2),(centroids(n,1)-count));
                end
                count=0;
                while rightVal==0
                    count=count+1;
                    rightVal=labeled(centroids(n,2),centroids(n,1)+count);
                end
                neighborCells=[neighborCells; [rightVal leftVal]];
            end 
            
            % Comprobar nodos vecinos dependiendo de las celdas que tocan
            app.neighbors={};
            for n=1:numNodes(1)
                neighbor=[];
                for m=1:numNodes(1)
                    if (n~=m)&&(~isempty(intersect(neighborCells(n,:),neighborCells(m,:))))&&(centroids(n,1)~=centroids(m,1))
                        neighbor=[neighbor m];
                        line([centroids(n,1) centroids(m,1)],[centroids(n,2), centroids(m,2)],'Color','m','Parent',app.UIAxes);
                    end
                end
                app.neighbors{end+1}=neighbor;
            end
            
            %Añadimos punto inicial y final
            minInitDist=1000;
            minFinalDist=1000;
            newInitNode=0;
            newFinalNode=0;
            for n=1:size(centroids,1)
                initDist=abs(app.Xinit-centroids(n,1))+abs(app.Yinit-centroids(n,2));
                if initDist<minInitDist
                    minInitDist=initDist;
                    newInitNode=n;
                end
                finalDist=abs(app.Xfinal-centroids(n,1))+abs(app.Yfinal-centroids(n,2));
                if finalDist<minFinalDist
                    minFinalDist=finalDist;
                    newFinalNode=n;
                end
            end
            app.desiredNode=newFinalNode;
            app.currentNode=newInitNode;
            app.nodeList=centroids;
        end

        % Button pushed function: PRMButton
        function PRMButtonPushed(app, event)
            % Step 1: generate nodes
            numNodes = 30;
            randNodes=[app.Yinit app.Xinit; app.Yfinal app.Xfinal];
            while size(randNodes(:,1))<numNodes
                randX = 1+round(rand*(app.rows-1));
                randY = 1+round(rand*(app.cols-1));
                if ~app.IMGPROCES(randX, randY) == 1
                    randNodes = [randNodes; randX randY];
                end
            end
            % Step 2: Connect nodes
            app.neighbors = {};
            for n=1:numNodes
                neighbor = [];
                for m=1:numNodes
                    add = 0;
                    if m~=n
                        add=improfile(~app.IMGPROCES,[randNodes(m,2),randNodes(n,2)],[randNodes(m,1),randNodes(n,1)]);
                        add=all(add);
                        if add
                            line([randNodes(m,2) randNodes(n,2)],[randNodes(m,1), randNodes(n,1)],'Color','m','Parent',app.UIAxes);
                            scatter(randNodes(n,2),randNodes(n,1),30,'g','filled','Parent',app.UIAxes);
                            neighbor=[neighbor m];
                        end
                    end
                end
                app.neighbors{end+1} = neighbor;
            end
            %scatter(randNodes(1,2),randNodes(1,1),30,'y','filled');
            %scatter(randNodes(2,2),randNodes(2,1),30,'y','filled');
            app.nodeList=[randNodes(:,2) randNodes(:,1)];
            app.currentNode=1;
            app.desiredNode=2;
        end

        % Button pushed function: RESETButton
        function RESETButtonPushed(app, event)
            hold(app.UIAxes,'off');
            clearvars -except app
            startupFcn(app);
        end

        % Button pushed function: BFSButton
        function BFSButtonPushed(app, event)
            pathFound=0;
            visitedNodes=[app.currentNode 0]; %Node, parent
            checkNode=zeros(1,size(app.nodeList(:,1),1));
            while ~pathFound
                for i=1:size(visitedNodes,1)
                    if visitedNodes(i)==app.desiredNode %Si es el final
                        goalParentID=visitedNodes(i,2);
                        pathFound=1;
                        break;
                    elseif checkNode(visitedNodes(i))==0
                        for j=1:size(app.neighbors{visitedNodes(i)},2)
                            if checkNode(app.neighbors{visitedNodes(i)}(j))==0
                                visitedNodes=[visitedNodes; app.neighbors{visitedNodes(i)}(j) visitedNodes(i)];
                                scatter(app.nodeList(app.neighbors{visitedNodes(i)}(j),1),app.nodeList(app.neighbors{visitedNodes(i)}(j),2),30,'y','filled','Parent',app.UIAxes);
                                drawnow;
                                pause(0.1);
                            end
                        end
                        checkNode(visitedNodes(i))=1;
                    end
                end
            end
            
            ok=0;
            pathNodes=[app.desiredNode];
            while ~ok
                for i=1:size(visitedNodes,1)
                    if visitedNodes(i,1)==goalParentID
                        pathNodes=[pathNodes visitedNodes(i,1)];
                        goalParentID=visitedNodes(i,2);
                        if goalParentID==0
                            ok=1;
                        end
                    end
                end
            end
            
            % Coordenadas del path
            app.path=[];
            for n=1:length(pathNodes)-1
                pathX=linspace(app.nodeList(pathNodes(n),1),app.nodeList(pathNodes(n+1),1),60);
                pathY=linspace(app.nodeList(pathNodes(n),2),app.nodeList(pathNodes(n+1),2),60);
                newPath=[pathY' pathX'];
                app.path = [app.path; newPath];
            end
            app.path=flip(round(app.path));
            scatter(app.path(:,2),app.path(:,1),5,'w','filled','Parent',app.UIAxes);
        end

        % Button pushed function: AButton
        function AButtonPushed(app, event)
            pathFound=0;
            visitedNodes=[app.currentNode 0 abs(app.Xfinal-app.nodeList(app.currentNode,1))+abs(app.Yfinal-app.nodeList(app.currentNode,2))]; %Node, parent
            checkNode=zeros(1,size(app.nodeList(:,1),1));
            while ~pathFound
                for i=1:size(visitedNodes,1)
                    if visitedNodes(i)==app.desiredNode %Si es el final
                        goalParentID=visitedNodes(i,2);
                        pathFound=1;
                        break;
                    elseif checkNode(visitedNodes(i))==0
                        for j=1:size(app.neighbors{visitedNodes(i)},2)
                            if checkNode(app.neighbors{visitedNodes(i)}(j))==0
                                visitedNodes=[visitedNodes; app.neighbors{visitedNodes(i)}(j) visitedNodes(i) (abs(app.Xfinal-app.nodeList(visitedNodes(i),1))+abs(app.Yfinal-app.nodeList(visitedNodes(i),2)))];
                                scatter(app.nodeList(app.neighbors{visitedNodes(i)}(j),1),app.nodeList(app.neighbors{visitedNodes(i)}(j),2),30,'y','filled','Parent',app.UIAxes);
                                drawnow;
                                pause(0.1);
                            end
                        end
                        checkNode(visitedNodes(i))=1;
                        visitedNodes=sortrows(visitedNodes,3);
                        break;
                    end
                end
            end
            
            ok=0;
            pathNodes=[app.desiredNode];
            while ~ok
                for i=1:size(visitedNodes,1)
                    if visitedNodes(i,1)==goalParentID
                        pathNodes=[pathNodes visitedNodes(i,1)];
                        goalParentID=visitedNodes(i,2);
                        if goalParentID==0
                            ok=1;
                        end
                    end
                end
            end
            
            % Coordenadas del path
            app.path=[];
            for n=1:length(pathNodes)-1
                pathX=linspace(app.nodeList(pathNodes(n),1),app.nodeList(pathNodes(n+1),1),60);
                pathY=linspace(app.nodeList(pathNodes(n),2),app.nodeList(pathNodes(n+1),2),60);
                newPath=[pathY' pathX'];
                app.path = [app.path; newPath];
            end
            app.path=flip(round(app.path));
            scatter(app.path(:,2),app.path(:,1),5,'w','filled','Parent',app.UIAxes);
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)
            rosinit
        end

        % Button pushed function: DisconnectButton
        function DisconnectButtonPushed(app, event)
            rosshutdown
        end

        % Button pushed function: FollowPathButton
        function FollowPathButtonPushed(app, event)
            %TODO
        end

        % Value changed function: TargetXEditField
        function TargetXEditFieldValueChanged(app, event)
            app.Xfinal = app.TargetXEditField.Value;
        end

        % Value changed function: TargetYEditField
        function TargetYEditFieldValueChanged(app, event)
            app.Yfinal = app.TargetYEditField.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.DiscretizationPanel = uipanel(app.UIFigure);
            app.DiscretizationPanel.Title = 'Discretization';
            app.VoronoiButton = uibutton(app.DiscretizationPanel, 'push');
            app.VoronoiButton.ButtonPushedFcn = createCallbackFcn(app, @VoronoiButtonPushed, true);
            app.VoronoiButton.Text = 'Voronoi';
            app.CellDecompositionButton = uibutton(app.DiscretizationPanel, 'push');
            app.CellDecompositionButton.ButtonPushedFcn = createCallbackFcn(app, @CellDecompositionButtonPushed, true);
            app.CellDecompositionButton.Text = 'Cell Decomposition';
            app.PRMButton = uibutton(app.DiscretizationPanel, 'push');
            app.PRMButton.ButtonPushedFcn = createCallbackFcn(app, @PRMButtonPushed, true);
            app.PRMButton.Text = 'PRM';
            app.GraphSearchingPanel = uipanel(app.UIFigure);
            app.GraphSearchingPanel.Title = 'Graph Searching';
            app.BFSButton = uibutton(app.GraphSearchingPanel, 'push');
            app.BFSButton.ButtonPushedFcn = createCallbackFcn(app, @BFSButtonPushed, true);
            app.BFSButton.Text = 'BFS';
            app.AButton = uibutton(app.GraphSearchingPanel, 'push');
            app.AButton.ButtonPushedFcn = createCallbackFcn(app, @AButtonPushed, true);
            app.AButton.Text = 'A*';
            app.TargetXEditFieldLabel = uilabel(app.UIFigure);
            app.TargetXEditFieldLabel.Text = 'Target X';
            app.TargetXEditField = uieditfield(app.UIFigure, 'numeric');
            app.TargetXEditField.ValueChangedFcn = createCallbackFcn(app, @TargetXEditFieldValueChanged, true);
            app.TargetYEditFieldLabel = uilabel(app.UIFigure);
            app.TargetYEditFieldLabel.Text = 'Target Y';
            app.TargetYEditField = uieditfield(app.UIFigure, 'numeric');
            app.TargetYEditField.ValueChangedFcn = createCallbackFcn(app, @TargetYEditFieldValueChanged, true);
            app.ROSinterfacePanel = uipanel(app.UIFigure);
            app.ROSinterfacePanel.Title = 'ROS interface';
            app.ConnectButton = uibutton(app.ROSinterfacePanel, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.FollowPathButton = uibutton(app.ROSinterfacePanel, 'push');
            app.FollowPathButton.ButtonPushedFcn = createCallbackFcn(app, @FollowPathButtonPushed, true);
            app.FollowPathButton.Text = 'Follow Path';
            app.DisconnectButton = uibutton(app.ROSinterfacePanel, 'push');
            app.DisconnectButton.ButtonPushedFcn = createCallbackFcn(app, @DisconnectButtonPushed, true);
            app.DisconnectButton.Text = 'Disconnect';
            app.RESETButton = uibutton(app.UIFigure, 'push');
            app.RESETButton.ButtonPushedFcn = createCallbackFcn(app, @RESETButtonPushed, true);
            app.RESETButton.Text = 'RESET';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = INTEDapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end